classdef DvbtAnalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DVBtAnalyzerUIFigure          matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        ShowConstellationMenu         matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        SearchforTPSDataButton        matlab.ui.control.Button
        ExtractMPEG2TSButton          matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        DVBTSignalAnalyzerLabel       matlab.ui.control.Label
        AddaBinaryFileEditFieldLabel  matlab.ui.control.Label
        AddaBinaryFileEditField       matlab.ui.control.EditField
        BrowseButton                  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        IdleLabel                     matlab.ui.control.Label
    end


    methods (Access = private)

        % Callback function: BrowseButton, OpenMenu
        function Browse(app, event)

            [FileName,FilePath]=uigetfile('*.*');
            if FileName ~=0
            d = [FilePath,FileName];
            app.AddaBinaryFileEditField.Value = d;
            end
            app.DVBtAnalyzerUIFigure.Visible = 'off';
            app.DVBtAnalyzerUIFigure.Visible = 'on';
        end

        % Callback function: ExitButton, ExitMenu
        function Exit(app, event)
           fclose('all');
           closereq;
        end

        % Menu selected function: ShowConstellationMenu
        function showCons(app, event)
            if strcmp(app.ShowConstellationMenu.Checked , 'on')
            app.ShowConstellationMenu.Checked = 'off';
            elseif strcmp(app.ShowConstellationMenu.Checked , 'off')
                app.ShowConstellationMenu.Checked = 'on';
                msgbox('this option will Heavily reduce performance','Warning','help')
            end
        end

        % Menu selected function: AboutMenu
        function About(app, event)
          Position = app.DVBtAnalyzerUIFigure.Position+[100,100,-200,-100];
         d = dialog('Position',Position,'Name','About');
          uicontrol('Parent',d,...
           'Style','text',...
           'Position',[20,120,300,40],...
           'String','CAUTIOUS !',...
           'FontSize',10,...
           'FontWeight','bold',...
           'HorizontalAlignment','left');
         uicontrol('Parent',d,...
           'Style','text',...
           'Position',[20,100,300,40],...
           'String','This App is designed for non-commercial use and provides Non-realTime DVBt Signal proccessing.',...
           'FontSize',9,...
           'HorizontalAlignment','left');
         uicontrol('Parent',d,...
           'Style','text',...
           'Position',[20,50,300,40],...
           'String','Contact Me: mhmdkh1992@gmail.com',...
           'FontSize',10,...
           'HorizontalAlignment','left')
         uicontrol('Parent',d,...
               'Position',[130 10 70 25],...
               'String','Close',...
               'Callback',@Close);
            function Close(~,~)
                delete(gcf)
            end
        end

        % Button pushed function: SearchforTPSDataButton
        function findTPS(app, event)
            clc;
            clear channelEstimate
            clear consDemod
            clear outerDeinterleaver
            clear ranDe
            clear RSdecoder
            clear symbolDeinterleaver
            clear innerDecoder
            clear bitDeinterleaver
            addpath('OFDM Demodulator')
            addpath('TPS detector')
            addpath('constelation Demodulator')
            addpath('Channel Coding Layer')
            InfoTable=[];
            if ~isempty(app.AddaBinaryFileEditField.Value)
            app.IdleLabel.Text = 'Busy...';
            pause(0.01)
            fid = fopen(app.AddaBinaryFileEditField.Value,'r');
            a = fread(fid,5e6,'float'); % 2*sampRate*sec
            a = a(1:2:end)+1i*a(2:2:end);
            fclose(fid);
            ofdm_samples=[zeros(100,1);a];
            clear a;
            inisig = ofdm_samples(1:(8*8192));
            guard = [1/4,1/8,1/16,1/32];
            Ni = [2048,8192];
            C  = cell(9,3);
            C(1,1:3) = {'name','testOut','varToAve'};
            Co=2;
            for i = Ni
                for j = guard
                    C{Co,1} = [num2str(i),'_',num2str(j)];
                    [C{Co,2},C{Co,3}] = correlator(inisig,j,i);
                    Co = Co+1;
                end
            end
            modDetect = sum([C{2,3},C{3,3},C{4,3},C{5,3}]>[C{6,3},C{7,3},C{8,3},C{9,3}]);
            if modDetect > 2
                modDetect=0;
            elseif modDetect < 2
                modDetect=1;
            else
                msgbox('Mode detection Faild','Error','error')
            end
         for guardAssumed = guard
            ni = Ni(modDetect+1);
            cInd = find(guard == guardAssumed)+4*modDetect+1;
            [timeOffset,fFrequencyOffset,cFrequencyOffset]=Synchronizer(inisig,...
                C{cInd,2},ni,guardAssumed);
            %% provide data from i'th OFDMSymbol for dciesion Devise (i = windowIndex)
            TPSdata=zeros(136,1);
            for i =1:136
                [ofdmData] = ofdmDemodulator(i,ofdm_samples,ni,guardAssumed,-1,timeOffset,fFrequencyOffset,...
                    cFrequencyOffset);
                TPSdata(i) = TPSdetector(ofdmData,ni);
            end
            %% TPS for confirmation or reconfiguration
            BPSKdemod = comm.DBPSKDemodulator;
            TPSbits  = BPSKdemod(TPSdata);
            pattern  = [0 0 1 1 0 1 0 1 1 1 1 0 1 1 1 0];
            TPSynch  =abs(conv(2*TPSbits-1, 2*pattern(end:-1:1)-1, 'valid'));
            [~,TPSindex] = max(TPSynch(2:68));
            decoded = bchdec(gf([TPSbits(TPSindex:TPSindex+67);zeros(59,1)].'),127,113);
            decoded = decoded(1:54);
            InfoTable=TPStable(decoded.x);
            if (str2num(InfoTable{7,2}) == guardAssumed &&...
                    (strcmp(InfoTable{8,2},'8K') && ni == 8192) && ~strcmp(InfoTable{1,2},'NOLL'))||...
                    (str2num(InfoTable{7,2}) == guardAssumed &&...
               (strcmp(InfoTable{8,2},'2K') && ni == 2048) && ~strcmp(InfoTable{1,2},'NOLL'))
               row1 = { InfoTable{1,2},InfoTable{4,2},[],InfoTable{8,2}};
               row2 = { InfoTable{2,2},InfoTable{5,2},InfoTable{6,2},InfoTable{7,2}};
               row3 = {InfoTable{3,2},[],[],[]};
               app.UITable.Data=[row1;row2;row3];
                break
            end
         end
         if isempty(InfoTable)
             msgbox('couldn''t Decode TPS data Successfullty','Error','error')
         end
            elseif isempty(app.AddaBinaryFileEditField.Value)
                msgbox('First Choose a File ...','FilePath is Empty','warn')
            end
            app.IdleLabel.Text = 'Idle';
        end

        % Button pushed function: ExtractMPEG2TSButton
        function ExtractMPEG(app, event)
            clc;
            clear channelEstimate
            clear consDemod
            clear outerDeinterleaver
            clear ranDe
            clear RSdecoder
            clear symbolDeinterleaver
            clear innerDecoder
            clear bitDeinterleaver
            addpath('OFDM Demodulator')
            addpath('TPS detector')
            addpath('constelation Demodulator')
            addpath('Channel Coding Layer')
            InfoTable=[];
            if ~isempty(app.AddaBinaryFileEditField.Value)
            app.IdleLabel.Text = 'acquiring initial parameters...';
            pause(0.01)
            fidr = fopen(app.AddaBinaryFileEditField.Value,'r');
            a = fread(fidr,4e6,'float'); % 2*sampRate*sec
            a = a(1:2:end)+1i*a(2:2:end);
            ofdm_samples=[zeros(100,1);a];
            clear a;
            inisig = ofdm_samples(1:(8*8192));
            guard = [1/4,1/8,1/16,1/32];
            Ni = [2048,8192];
            C  = cell(9,3);
            C(1,1:3) = {'name','testOut','varToAve'};
            Co=2;
            for i = Ni
                for j = guard
                    C{Co,1} = [num2str(i),'_',num2str(j)];
                    [C{Co,2},C{Co,3}] = correlator(inisig,j,i);
                    Co = Co+1;
                end
            end
            modDetect = sum([C{2,3},C{3,3},C{4,3},C{5,3}]>[C{6,3},C{7,3},C{8,3},C{9,3}]);
            if modDetect > 2
                modDetect=0;
            elseif modDetect < 2
                modDetect=1;
            else
                msgbox('Mode detection Faild','Error','error')
            end
         for guardAssumed = guard
            ni = Ni(modDetect+1);
            cInd = find(guard == guardAssumed)+4*modDetect+1;
            [timeOffset,fFrequencyOffset,cFrequencyOffset]=Synchronizer(inisig,...
                C{cInd,2},ni,guardAssumed);
            %% provide data from i'th OFDMSymbol for dciesion Devise (i = windowIndex)
            TPSdata=zeros(136,1);
            for i =1:136
                [ofdmData] = ofdmDemodulator(i,ofdm_samples,ni,guardAssumed,-1,timeOffset,fFrequencyOffset,...
                    cFrequencyOffset);
                TPSdata(i) = TPSdetector(ofdmData,ni);
            end
            %% TPS for confirmation or reconfiguration
            BPSKdemod = comm.DBPSKDemodulator;
            TPSbits  = BPSKdemod(TPSdata);
            pattern  = [0 0 1 1 0 1 0 1 1 1 1 0 1 1 1 0];
            TPSynch  =abs(conv(2*TPSbits-1, 2*pattern(end:-1:1)-1, 'valid'));
            [~,TPSindex] = max(TPSynch(2:68));
            decoded = bchdec(gf([TPSbits(TPSindex:TPSindex+67);zeros(59,1)].'),127,113);
            decoded = decoded(1:54);
            InfoTable=TPStable(decoded.x);
            if (str2num(InfoTable{7,2}) == guardAssumed &&...
                    (strcmp(InfoTable{8,2},'8K') && ni == 8192) && ~strcmp(InfoTable{1,2},'NOLL'))||...
                    (str2num(InfoTable{7,2}) == guardAssumed &&...
               (strcmp(InfoTable{8,2},'2K') && ni == 2048) && ~strcmp(InfoTable{1,2},'NOLL'))
               row1 = { InfoTable{1,2},InfoTable{4,2},[],InfoTable{8,2}};
               row2 = { InfoTable{2,2},InfoTable{5,2},InfoTable{6,2},InfoTable{7,2}};
               row3 = {InfoTable{3,2},[],[],[]};
               app.UITable.Data=[row1;row2;row3];
                break
            end
         end
         if isempty(InfoTable)
             msgbox('couldn''t Decode TPS data Successfullty','Error','error')
         end
            app.IdleLabel.Text = 'waiting for user...';
            selpath = uiputfile('*.ts','save as:','output');
            app.DVBtAnalyzerUIFigure.Visible = 'off';
            app.DVBtAnalyzerUIFigure.Visible = 'on';
            progressbar = uiprogressdlg(app.DVBtAnalyzerUIFigure,'Value',0,'Message','Extracting MPEG-2 Packets',...
                'Title','Please wait','Cancelable','on','ShowPercentage','on');
            app.IdleLabel.Text = 'Extracting Data ...';
            pause(0.01)
            TPSdata = zeros(68,1);
            forprogressbar_acc =0;
            s=dir(app.AddaBinaryFileEditField.Value);
            filesize = s.bytes/(32*2)*8;
            fidw = fopen(selpath,'w');
            conutFirstPlace = 4e6;
            count = conutFirstPlace;
            GL = guardAssumed*ni;
            OSN = 0;
            endingFlag = 0;
            while(endingFlag == 0)
                if progressbar.CancelRequested ==1
                    endingFlag = 1;
                    close;
                end
                lastSample = timeOffset-1+(TPSindex+OSN)*(ni+GL);
                forprogressbar=forprogressbar_acc+lastSample;
                if forprogressbar>=filesize
                    forprogressbar=filesize;
                end
                progressbar.Value =  forprogressbar./filesize;
                if lastSample <=length(ofdm_samples)
                    ofdmData = ofdmDemodulator(TPSindex+OSN,ofdm_samples,ni,guardAssumed,mod(OSN,68),...
                        timeOffset,fFrequencyOffset,cFrequencyOffset);
                    TPSdata(mod(OSN,68)+1,1) = TPSdetector(ofdmData,ni);
                    demodOut=consDemod(ofdmData,ni,mod(OSN,68),InfoTable{4,2},app.ShowConstellationMenu.Checked);
                    if mod(OSN,68) == 0 && OSN ~= 0
                        BPSKdemod = comm.DBPSKDemodulator;
                        TPSbits  = BPSKdemod(TPSdata(1:68));
                        decoded= bchdec(gf([TPSbits;zeros(59,1)].'),127,113);
                        decoded = decoded(1:54);
                        infoTa=TPStable(decoded.x);
                        fprintf('%s decoded Successfully \n',infoTa{3,2});
                    end
                    if  OSN > 4
                    yp=symbolDeinterleaver(demodOut,ni,mod(OSN,68));
                    [b,v]=bitDeinterleaver(yp,ni);
                    x=muxer(b,v);
                    toOuterInterleaver=innerDecoder(x,InfoTable{6,2});
                    toRSdec=outerDeinterleaver(toOuterInterleaver);
                    [toDerand,syncErrorFlag]=RSdecoder(toRSdec);
                        if syncErrorFlag ==0
                            tofile=ranDe(toDerand);
                            fwrite(fidw,tofile,'uint8');
                            OSN = OSN+1;
                        elseif syncErrorFlag==1
                            fprintf('synchronization Error\n')
                            if count == conutFirstPlace
                                [a,count] = fread(fidr,count,'float');
                                a = a(1:2:end)+1i*a(2:2:end);
                                %if lastSample>68*ni && OSN ~=6
                                %    ofdm_samples=[ofdm_samples((lastSample-68*ni):end);a];
                                %    forprogressbar_acc = forprogressbar_acc+lastSample-68*ni;
                                %elseif lastSample<68*ni || OSN ==6
                                    ofdm_samples=[ofdm_samples((lastSample):end);a];
                                    forprogressbar_acc = forprogressbar_acc+lastSample;
                                %end
                            elseif count ~= conutFirstPlace
                                  endingFlag = 1;
                                  progressbar.Value=1;
                            end
                            clear channelEstimate
                            clear outerDeinterleaver
                            clear ranDe
                            clear RSdecoder
                            clear innerDecoder
                            clear bitDeinterleaver
                            inisig = ofdm_samples(1:(6*8192));
                            corrinfo=correlator(inisig,guardAssumed,ni);
                            [timeOffset,fFrequencyOffset,cFrequencyOffset]=Synchronizer(inisig,...
                            corrinfo,ni,guardAssumed);
                            TPSdata=zeros(136,1);
                            for i =1:136
                                [ofdmData] = ofdmDemodulator(i,ofdm_samples,ni,guardAssumed,-1,timeOffset,fFrequencyOffset,...
                                    cFrequencyOffset);
                                TPSdata(i) = TPSdetector(ofdmData,ni);
                            end
                            BPSKdemod = comm.DBPSKDemodulator;
                            TPSbits  = BPSKdemod(TPSdata);
                            pattern  = [0 0 1 1 0 1 0 1 1 1 1 0 1 1 1 0];
                            TPSynch  =abs(conv(2*TPSbits-1, 2*pattern(end:-1:1)-1, 'valid'));
                            [~,TPSindex] = max(TPSynch(2:68));
                            OSN = 0;
                        end
                    elseif OSN <= 4
                        OSN=OSN+1;
                    end
                elseif lastSample>length(ofdm_samples)
                    if count == conutFirstPlace
                    [a,count] = fread(fidr,count,'float');
                    a = a(1:2:end)+1i*a(2:2:end);
                    ofdm_samples=[ofdm_samples;a];
                    elseif count ~= conutFirstPlace
                        endingFlag = 1;
                        progressbar.Value=1;
                    end
                end
            end
            fclose(fidr);
            fclose(fidw);
            app.IdleLabel.Text = 'Idel';
            elseif isempty(app.AddaBinaryFileEditField.Value)
                msgbox('First Choose a File ...','FilePath is Empty','warn')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DVBtAnalyzerUIFigure = uifigure;
            app.DVBtAnalyzerUIFigure.Name = 'DVBt Analyzer';
            app.FileMenu = uimenu(app.DVBtAnalyzerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @Browse, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open ...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @Exit, true);
            app.ExitMenu.Text = 'Exit';
            app.OptionsMenu = uimenu(app.DVBtAnalyzerUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ShowConstellationMenu = uimenu(app.OptionsMenu);
            app.ShowConstellationMenu.MenuSelectedFcn = createCallbackFcn(app, @showCons, true);
            app.ShowConstellationMenu.Text = 'Show Constellation';
            app.HelpMenu = uimenu(app.DVBtAnalyzerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @About, true);
            app.AboutMenu.Text = 'About';
            app.SearchforTPSDataButton = uibutton(app.DVBtAnalyzerUIFigure, 'push');
            app.SearchforTPSDataButton.ButtonPushedFcn = createCallbackFcn(app, @findTPS, true);
            app.SearchforTPSDataButton.Text = 'Search for TPS Data';
            app.ExtractMPEG2TSButton = uibutton(app.DVBtAnalyzerUIFigure, 'push');
            app.ExtractMPEG2TSButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractMPEG, true);
            app.ExtractMPEG2TSButton.Text = 'Extract MPEG2 .TS';
            app.ExitButton = uibutton(app.DVBtAnalyzerUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @Exit, true);
            app.ExitButton.Text = 'Exit';
            app.DVBTSignalAnalyzerLabel = uilabel(app.DVBtAnalyzerUIFigure);
            app.DVBTSignalAnalyzerLabel.Text = 'DVB-T Signal Analyzer';
            app.AddaBinaryFileEditFieldLabel = uilabel(app.DVBtAnalyzerUIFigure);
            app.AddaBinaryFileEditFieldLabel.Text = 'Add a Binary File :';
            app.AddaBinaryFileEditField = uieditfield(app.DVBtAnalyzerUIFigure, 'text');
            app.BrowseButton = uibutton(app.DVBtAnalyzerUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @Browse, true);
            app.BrowseButton.Text = 'Browse';
            app.UITable = uitable(app.DVBtAnalyzerUIFigure);
            app.UITable.ColumnName = {'Sync Info'; 'Modulation Type'; 'CR'; 'Mode'};
            app.UITable.RowName = {};
            app.IdleLabel = uilabel(app.DVBtAnalyzerUIFigure);
            app.IdleLabel.Text = 'Idle';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DvbtAnalizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DVBtAnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DVBtAnalyzerUIFigure)
        end
    end
end